classdef ImageFeaturesAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CataloguePanel                matlab.ui.container.Panel
        ExtensionDropDownLabel        matlab.ui.control.Label
        ExtensionDropDown             matlab.ui.control.DropDown
        ChoosepathButton              matlab.ui.control.Button
        ImagesPanel                   matlab.ui.container.Panel
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        PreviousButton                matlab.ui.control.Button
        NextButton                    matlab.ui.control.Button
        stimageEditFieldLabel         matlab.ui.control.Label
        stimageEditField              matlab.ui.control.NumericEditField
        ndimageEditFieldLabel         matlab.ui.control.Label
        ndimageEditField              matlab.ui.control.NumericEditField
        Label                         matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        OptionsPanel                  matlab.ui.container.Panel
        DescriptorsButtonGroup        matlab.ui.container.ButtonGroup
        SURFButton                    matlab.ui.control.ToggleButton
        BRISKButton                   matlab.ui.control.ToggleButton
        HOGButton                     matlab.ui.control.ToggleButton
        FASTButton                    matlab.ui.control.ToggleButton
        ORBButton                     matlab.ui.control.ToggleButton
        MSERButton                    matlab.ui.control.ToggleButton
        HARRISButton                  matlab.ui.control.ToggleButton
        ColormodelSwitchLabel         matlab.ui.control.Label
        ColormodelSwitch              matlab.ui.control.Switch
        SimilaritythresholdEditFieldLabel  matlab.ui.control.Label
        SimilaritythresholdEditField  matlab.ui.control.NumericEditField
        CompareButton                 matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
    end

    
    properties (Access = public)
        numberOfFiles;  %number of all files
        I;              %first image
        I2;             %second image
        currentImage1Number =1;
        currentImage2Number =2;
        myFolder;
        theFiles;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChoosepathButton
        function ChoosepathButtonPushed(app, event)
            % Specify the folder where the files live. 
app.myFolder = uigetdir('E:\studia\VI sem\Moje\PAZIG\PAzIG-Projekt\Sekwencja', 'Choose directory')                                              %obsługa wyjątku kiedy nic nie wybierzemy
% Check to make sure that folder actually exists.  Warn user if it doesn't.
if ~isdir(app.myFolder)
  errorMessage = sprintf('Error: The following folder does not exist:\n%s', app.myFolder);
  uiwait(warndlg(errorMessage));
  return;
end
% Get a list of all files in the folder with the desired file name pattern.
filePattern = fullfile(app.myFolder, '*.jpg'); % Change to whatever pattern you need.           %case do roznych wybranych rozszerzen
app.theFiles = dir(filePattern);
for k = 1: length(app.theFiles)
  baseFileName = app.theFiles(k).name;
  fullFileName = fullfile(app.myFolder, baseFileName);
end
  file1 = fullfile(app.myFolder, app.theFiles(1).name);
  file2 = fullfile(app.myFolder, app.theFiles(2).name);
    app.I = imread(file1);
    imshow(app.I,'Parent', app.UIAxes);
    app.I2 = imread(file2);
    imshow(app.I2, 'Parent', app.UIAxes_2 );
  
  drawnow; % Force display to update immediately.
  app.numberOfFiles = length(app.theFiles);
  formatSpec = '/%d';
    app.Label.Text = sprintf(formatSpec,app.numberOfFiles);
    app.Label_2.Text = sprintf(formatSpec,app.numberOfFiles);
        end

        % Value changed function: stimageEditField
        function stimageEditFieldValueChanged(app, event)
            value = app.stimageEditField.Value;
            if value > app.currentImage2Number
                warndlg('1st image must be befor 2nd image')
            else
            file1 = fullfile(app.myFolder, app.theFiles(value).name);
            app.I = imread(file1);
            imshow(app.I,'Parent', app.UIAxes);
            drawnow;
            app.currentImage1Number = value;
            end
        end

        % Value changed function: ndimageEditField
        function ndimageEditFieldValueChanged(app, event)
            value = app.ndimageEditField.Value;
            if value < app.currentImage1Number
                warndlg('2nd image must be after 1st image')
            else
            file2 = fullfile(app.myFolder, app.theFiles(value).name);
            app.I2 = imread(file2);
            imshow(app.I2,'Parent', app.UIAxes_2);
            drawnow;
            app.currentImage2Number = value;
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if(app.currentImage2Number == app.numberOfFiles)
            file1 = fullfile(app.myFolder, app.theFiles(1).name);
            app.I = imread(file1);
            imshow(app.I,'Parent', app.UIAxes);
            drawnow;           
            app.currentImage1Number = 1; 
            app.stimageEditField.Value = app.currentImage1Number;
            
            file2 = fullfile(app.myFolder, app.theFiles(2).name);
            app.I2 = imread(file2);
            imshow(app.I2,'Parent', app.UIAxes_2);
            drawnow;
            app.currentImage2Number = 2; 
            app.ndimageEditField.Value = app.currentImage2Number;
            else 
                
            file1 = fullfile(app.myFolder, app.theFiles(app.currentImage1Number+1).name);
            app.I = imread(file1);
            imshow(app.I,'Parent', app.UIAxes);
            drawnow;           
            app.currentImage1Number=app.currentImage1Number+1;
            app.stimageEditField.Value = app.currentImage1Number;
            
            file2 = fullfile(app.myFolder, app.theFiles(app.currentImage2Number+1).name);
            app.I2 = imread(file2);
            imshow(app.I2,'Parent', app.UIAxes_2);
            drawnow;
            app.currentImage2Number = app.currentImage2Number + 1; 
            app.ndimageEditField.Value = app.currentImage2Number;
            end

                
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            if(app.currentImage1Number == 1)
            file1 = fullfile(app.myFolder, app.theFiles(app.numberOfFiles-1).name);
            app.I = imread(file1);
            imshow(app.I,'Parent', app.UIAxes);
            drawnow;           
            app.currentImage1Number = app.numberOfFiles-1; 
            app.stimageEditField.Value = app.currentImage1Number;
            
            file2 = fullfile(app.myFolder, app.theFiles(app.numberOfFiles).name);
            app.I2 = imread(file2);
            imshow(app.I2,'Parent', app.UIAxes_2);
            drawnow;
            app.currentImage2Number = app.numberOfFiles; 
            app.ndimageEditField.Value = app.currentImage2Number;
            else 
                
            file1 = fullfile(app.myFolder, app.theFiles(app.currentImage1Number-1).name);
            app.I = imread(file1);
            imshow(app.I,'Parent', app.UIAxes);
            drawnow;           
            app.currentImage1Number=app.currentImage1Number-1;
            app.stimageEditField.Value = app.currentImage1Number;
            
            file2 = fullfile(app.myFolder, app.theFiles(app.currentImage2Number-1).name);
            app.I2 = imread(file2);
            imshow(app.I2,'Parent', app.UIAxes_2);
            drawnow;
            app.currentImage2Number = app.currentImage2Number-1; 
            app.ndimageEditField.Value = app.currentImage2Number;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.CataloguePanel = uipanel(app.UIFigure);
            app.CataloguePanel.Title = 'Catalogue';
            app.ExtensionDropDownLabel = uilabel(app.CataloguePanel);
            app.ExtensionDropDownLabel.Text = 'Extension';
            app.ExtensionDropDown = uidropdown(app.CataloguePanel);
            app.ExtensionDropDown.Items = {'.tif', '.dcm', '.jpg', '.png'};
            app.ExtensionDropDown.Value = '.tif';
            app.ChoosepathButton = uibutton(app.CataloguePanel, 'push');
            app.ChoosepathButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosepathButtonPushed, true);
            app.ChoosepathButton.Text = 'Choose path';
            app.ImagesPanel = uipanel(app.UIFigure);
            app.ImagesPanel.Title = 'Images';
            app.UIAxes_2 = uiaxes(app.ImagesPanel);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes = uiaxes(app.ImagesPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.PreviousButton = uibutton(app.ImagesPanel, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.ImagesPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.stimageEditFieldLabel = uilabel(app.ImagesPanel);
            app.stimageEditFieldLabel.Text = '1st image';
            app.stimageEditField = uieditfield(app.ImagesPanel, 'numeric');
            app.stimageEditField.ValueChangedFcn = createCallbackFcn(app, @stimageEditFieldValueChanged, true);
            app.stimageEditField.Value = 1;
            app.ndimageEditFieldLabel = uilabel(app.ImagesPanel);
            app.ndimageEditFieldLabel.Text = '2nd image';
            app.ndimageEditField = uieditfield(app.ImagesPanel, 'numeric');
            app.ndimageEditField.ValueChangedFcn = createCallbackFcn(app, @ndimageEditFieldValueChanged, true);
            app.ndimageEditField.Value = 2;
            app.Label = uilabel(app.ImagesPanel);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.ImagesPanel);
            app.Label_2.Text = '';
            app.OptionsPanel = uipanel(app.UIFigure);
            app.OptionsPanel.Title = 'Options';
            app.DescriptorsButtonGroup = uibuttongroup(app.OptionsPanel);
            app.DescriptorsButtonGroup.Title = 'Descriptors';
            app.SURFButton = uitogglebutton(app.DescriptorsButtonGroup);
            app.SURFButton.Text = 'SURF';
            app.SURFButton.Value = true;
            app.BRISKButton = uitogglebutton(app.DescriptorsButtonGroup);
            app.BRISKButton.Text = 'BRISK';
            app.HOGButton = uitogglebutton(app.DescriptorsButtonGroup);
            app.HOGButton.Text = 'HOG';
            app.FASTButton = uitogglebutton(app.DescriptorsButtonGroup);
            app.FASTButton.Text = 'FAST';
            app.ORBButton = uitogglebutton(app.DescriptorsButtonGroup);
            app.ORBButton.Text = 'ORB';
            app.MSERButton = uitogglebutton(app.DescriptorsButtonGroup);
            app.MSERButton.Text = 'MSER';
            app.HARRISButton = uitogglebutton(app.DescriptorsButtonGroup);
            app.HARRISButton.Text = 'HARRIS';
            app.ColormodelSwitchLabel = uilabel(app.OptionsPanel);
            app.ColormodelSwitchLabel.Text = 'Color model';
            app.ColormodelSwitch = uiswitch(app.OptionsPanel, 'slider');
            app.ColormodelSwitch.Items = {'RGB', 'G+B'};
            app.ColormodelSwitch.Value = 'RGB';
            app.SimilaritythresholdEditFieldLabel = uilabel(app.OptionsPanel);
            app.SimilaritythresholdEditFieldLabel.Text = 'Similarity threshold ';
            app.SimilaritythresholdEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.CompareButton = uibutton(app.OptionsPanel, 'push');
            app.CompareButton.Text = 'Compare';
            app.SaveButton = uibutton(app.OptionsPanel, 'push');
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageFeaturesAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end